classdef eyeCalibration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        VideoCollectionTab              matlab.ui.container.Tab
        CameraParametersLabel           matlab.ui.control.Label
        PresetsDropDownLabel            matlab.ui.control.Label
        PresetsDropDown                 matlab.ui.control.DropDown
        CameraResolutionEditFieldLabel  matlab.ui.control.Label
        CameraResolutionEditField       matlab.ui.control.NumericEditField
        BoxSizeEditFieldLabel           matlab.ui.control.Label
        BoxSizeEditField                matlab.ui.control.NumericEditField
        BoxOffsetEditFieldLabel         matlab.ui.control.Label
        BoxOffsetEditField              matlab.ui.control.NumericEditField
        CameraResolutionEditField_2     matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        SetupCamerasButton              matlab.ui.control.Button
        CollectDataButton               matlab.ui.control.Button
        NumberofframestocollectEditFieldLabel  matlab.ui.control.Label
        NumberofframestocollectEditField  matlab.ui.control.NumericEditField
        OfflineEyeTrackingTab           matlab.ui.container.Tab
        RadiusPupilEditFieldLabel       matlab.ui.control.Label
        RadiusPupilEditField            matlab.ui.control.NumericEditField
        XPaddingEditFieldLabel          matlab.ui.control.Label
        XPaddingEditField               matlab.ui.control.NumericEditField
        YPaddingEditFieldLabel          matlab.ui.control.Label
        YPaddingEditField               matlab.ui.control.NumericEditField
        RadiusCornealReflection1EditFieldLabel  matlab.ui.control.Label
        RadiusCornealReflection1EditField  matlab.ui.control.NumericEditField
        RadiusCornealReflection2Label   matlab.ui.control.Label
        RadiusCornealReflection2EditField  matlab.ui.control.NumericEditField
        MinimumFeaturesEditFieldLabel   matlab.ui.control.Label
        MinimumFeaturesEditField        matlab.ui.control.NumericEditField
        radiiCR1EditField_2             matlab.ui.control.NumericEditField
        radiiCR2EditField_2             matlab.ui.control.NumericEditField
        ManualPupilLocationSelectionCheckBox  matlab.ui.control.CheckBox
        ImproveImageContrastCheckBox    matlab.ui.control.CheckBox
        TestParametersButton            matlab.ui.control.Button
        RunAnalysisButton               matlab.ui.control.Button
        findmagscaleVelButton           matlab.ui.control.Button
        EyeAnalysisTab                  matlab.ui.container.Tab
    end

    
    methods (Access = private)
        
        function results = setupVideo(app, roiInitial)
            % make sure that there are no other connection to other video input
            imaqreset;
            
            % Videoinput makes sure that the connection between PC and camera exists.
            vid(1) = videoinput('winvideo',1,'MJPG_1280x1024');
            vid(2) = videoinput('winvideo',2,'MJPG_1280x1024');
            
            % Set up the camera
            set(vid,'FramesPerTrigger',1);
            set(vid,'TriggerRepeat',Inf);
            
            % Instead of default config, use our 'vid' parameters file.
            % Keep recording forever until we stop it.
            triggerconfig(vid,'manual');
            
            % Get a grayscale image
            set(vid,'ReturnedColorSpace','grayscale');
            
            %% Set exposure and gain
            for i = 1:2
                src = getselectedsource(vid(i));
                src.Exposure = -10; % changing the exposure changes the frames per second. Higher exposure == lower frames per second
                frameRates = set(src, 'FrameRate');
                src.FrameRate = frameRates{1}; % Highest frame rate = 30 fps
            end
            
            set(vid(1), 'ROIPosition',roiInitial);
            set(vid(2), 'ROIPosition',roiInitial);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SetupCamerasButton
        function SetupCamerasButtonPushed(app, event)
            % get parameters from gui
            res = [app.CameraResolutionEditField.Value app.CameraResolutionEditField_2.Value];
            boxSize = app.BoxSizeEditField.Value;
            boxOffset = app.BoxOffsetEditField.Value;
            
            % [x_offest, y_offset, width, length]
            roiInitial = [(res-boxSize)/2 boxSize boxSize]+[0 boxOffset 0 0];
            
            
            % Set the camera specific parameters. (frame rate, exposure, etc...)
            vid = setupvideo_new2019(roiInitial); pause(.1)
            
            % Show preview until figure closed
            % img1 and img2 are 'cubes' or 'stacks' of images (not from experiment)
            [img1, img2] = eyecam(vid); % ***Run "eyecam(vid);" to see preview at any point ***%
    
            % Prompt for ROI around one eye - first click on camera image from the left
            % side, then click-drag to select rectangle
            vid = setROI(vid,img1, img2, res, boxSize, boxOffset);

        end

        % Button pushed function: CollectDataButton
        function CollectDataButtonPushed(app, event)
            % Prompt User for calibration folder location
            folder = uigetdir(cd, 'Select Calibration Folder');

            if exist(fullfile(folder,'img1.tiff'),'file')
                warning('Overwrite')
                delete(fullfile(pwd, 'img1.tiff'),fullfile(pwd,'img2.tiff'))
            end
            
            close all
            eyetrackon = 1;
            nframes = 30*120; % 30*nseconds
            eyecam(vid,eyetrackon,nframes); % results = time stamps, images are saves as img1 and 2
            warning on
        end

        % Button pushed function: TestParametersButton
        function TestParametersButtonPushed(app, event)
            radiiPupil = app.RadiusPupilEditField.Value;
            xpad = app.XPaddingEditField.Value;
            ypad = app.YPaddingEditField.Value;
            radiiCR1 = [app.RadiusCornealReflection1EditField.Value app.radiiCR1EditField_2.Value];
            radiiCR2 = [app.RadiusCornealReflection2EditField.Value app.radiiCR2EditField_2.Value];
            minfeatures = app.MinimumFeaturesEditField.Value;
            imAdjust = app.ImproveImageContrastCheckBox.Value;
            manual = app.ManualPupilLocationSelectionCheckBox.Value;
            videoTest(radiiPupil,[xpad ypad],radiiCR1,radiiCR2,minfeatures,'ImAdjust',imAdjust,'Manual',manual)
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            eyeAnalysis
            % Save results
            saveas(1,'eyetrack1.jpg');
            saveas(2,'eyetrack2.jpg');
        end

        % Button pushed function: findmagscaleVelButton
        function findmagscaleVelButtonPushed(app, event)
            findmagscaleVel
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.VideoCollectionTab = uitab(app.TabGroup);
            app.VideoCollectionTab.Title = 'Video Collection';
            app.CameraParametersLabel = uilabel(app.VideoCollectionTab);
            app.CameraParametersLabel.Text = 'Camera Parameters';
            app.PresetsDropDownLabel = uilabel(app.VideoCollectionTab);
            app.PresetsDropDownLabel.Text = 'Presets';
            app.PresetsDropDown = uidropdown(app.VideoCollectionTab);
            app.PresetsDropDown.Items = {'ELP', 'Custom'};
            app.PresetsDropDown.Value = 'ELP';
            app.CameraResolutionEditFieldLabel = uilabel(app.VideoCollectionTab);
            app.CameraResolutionEditFieldLabel.Text = 'Camera Resolution';
            app.CameraResolutionEditField = uieditfield(app.VideoCollectionTab, 'numeric');
            app.CameraResolutionEditField.Tooltip = {'Camera Resolution'};
            app.CameraResolutionEditField.Value = 1280;
            app.BoxSizeEditFieldLabel = uilabel(app.VideoCollectionTab);
            app.BoxSizeEditFieldLabel.Text = 'Box Size';
            app.BoxSizeEditField = uieditfield(app.VideoCollectionTab, 'numeric');
            app.BoxSizeEditField.Tooltip = {'Length and Width of the square during initial view. (a crop of the resolution)'};
            app.BoxSizeEditField.Value = 500;
            app.BoxOffsetEditFieldLabel = uilabel(app.VideoCollectionTab);
            app.BoxOffsetEditFieldLabel.Text = 'Box Offset';
            app.BoxOffsetEditField = uieditfield(app.VideoCollectionTab, 'numeric');
            app.BoxOffsetEditField.Tooltip = {'Vertical Offset (pixels) below the line at (res(2)-box)/2 pixels above the bottom of the camera''s full field of view (indicated by'; 'res)'};
            app.CameraResolutionEditField_2 = uieditfield(app.VideoCollectionTab, 'numeric');
            app.CameraResolutionEditField_2.Value = 1024;
            app.UIAxes = uiaxes(app.VideoCollectionTab);
            title(app.UIAxes, 'Camera 1')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.VideoCollectionTab);
            title(app.UIAxes_2, 'Camera 1')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.SetupCamerasButton = uibutton(app.VideoCollectionTab, 'push');
            app.SetupCamerasButton.ButtonPushedFcn = createCallbackFcn(app, @SetupCamerasButtonPushed, true);
            app.SetupCamerasButton.Text = 'Setup Cameras';
            app.CollectDataButton = uibutton(app.VideoCollectionTab, 'push');
            app.CollectDataButton.ButtonPushedFcn = createCallbackFcn(app, @CollectDataButtonPushed, true);
            app.CollectDataButton.Text = 'Collect Data';
            app.NumberofframestocollectEditFieldLabel = uilabel(app.VideoCollectionTab);
            app.NumberofframestocollectEditFieldLabel.Text = 'Number of frames to collect';
            app.NumberofframestocollectEditField = uieditfield(app.VideoCollectionTab, 'numeric');
            app.NumberofframestocollectEditField.Tooltip = {'@ 30fps for 120 seconds'};
            app.NumberofframestocollectEditField.Value = 3600;
            app.OfflineEyeTrackingTab = uitab(app.TabGroup);
            app.OfflineEyeTrackingTab.Title = 'Offline Eye Tracking';
            app.RadiusPupilEditFieldLabel = uilabel(app.OfflineEyeTrackingTab);
            app.RadiusPupilEditFieldLabel.Text = 'Radius: Pupil';
            app.RadiusPupilEditField = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.RadiusPupilEditField.Tooltip = {'radius in pixels'};
            app.RadiusPupilEditField.Value = 50;
            app.XPaddingEditFieldLabel = uilabel(app.OfflineEyeTrackingTab);
            app.XPaddingEditFieldLabel.Text = 'X - Padding';
            app.XPaddingEditField = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.XPaddingEditField.Tooltip = {'X Padding around Pupil in pixels'};
            app.XPaddingEditField.Value = 100;
            app.YPaddingEditFieldLabel = uilabel(app.OfflineEyeTrackingTab);
            app.YPaddingEditFieldLabel.Text = 'Y - Padding';
            app.YPaddingEditField = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.YPaddingEditField.Tooltip = {'Y Padding around Pupil in pixels'};
            app.YPaddingEditField.Value = 40;
            app.RadiusCornealReflection1EditFieldLabel = uilabel(app.OfflineEyeTrackingTab);
            app.RadiusCornealReflection1EditFieldLabel.Text = 'Radius: Corneal Reflection 1 ';
            app.RadiusCornealReflection1EditField = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.RadiusCornealReflection1EditField.Value = 6;
            app.RadiusCornealReflection2Label = uilabel(app.OfflineEyeTrackingTab);
            app.RadiusCornealReflection2Label.Text = 'Radius: Corneal Reflection 2';
            app.RadiusCornealReflection2EditField = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.RadiusCornealReflection2EditField.Value = 6;
            app.MinimumFeaturesEditFieldLabel = uilabel(app.OfflineEyeTrackingTab);
            app.MinimumFeaturesEditFieldLabel.Text = 'Minimum Features';
            app.MinimumFeaturesEditField = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.MinimumFeaturesEditField.Tooltip = {'Fraction of features needed for pupil detection'};
            app.MinimumFeaturesEditField.Value = 0.5;
            app.radiiCR1EditField_2 = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.radiiCR1EditField_2.Value = 12;
            app.radiiCR2EditField_2 = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.radiiCR2EditField_2.Value = 12;
            app.ManualPupilLocationSelectionCheckBox = uicheckbox(app.OfflineEyeTrackingTab);
            app.ManualPupilLocationSelectionCheckBox.Text = 'Manual Pupil Location Selection';
            app.ManualPupilLocationSelectionCheckBox.Value = true;
            app.ImproveImageContrastCheckBox = uicheckbox(app.OfflineEyeTrackingTab);
            app.ImproveImageContrastCheckBox.Text = 'Improve Image Contrast';
            app.ImproveImageContrastCheckBox.Value = true;
            app.TestParametersButton = uibutton(app.OfflineEyeTrackingTab, 'push');
            app.TestParametersButton.ButtonPushedFcn = createCallbackFcn(app, @TestParametersButtonPushed, true);
            app.TestParametersButton.Text = 'Test Parameters';
            app.RunAnalysisButton = uibutton(app.OfflineEyeTrackingTab, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.EyeAnalysisTab = uitab(app.TabGroup);
            app.EyeAnalysisTab.Title = 'Eye Analysis';
            app.findmagscaleVelButton = uibutton(app.EyeAnalysisTab, 'push');
            app.findmagscaleVelButton.ButtonPushedFcn = createCallbackFcn(app, @findmagscaleVelButtonPushed, true);
            app.findmagscaleVelButton.Text = 'findmagscaleVel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eyeCalibration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end